classdef appbuilding1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResultsTextArea               matlab.ui.control.TextArea
        ResultsTextAreaLabel          matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        SolveButton                   matlab.ui.control.Button
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        ToleranceEditField            matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel       matlab.ui.control.Label
        X1EditField                   matlab.ui.control.NumericEditField
        X1EditFieldLabel              matlab.ui.control.Label
        X0EditField                   matlab.ui.control.NumericEditField
        X0EditFieldLabel              matlab.ui.control.Label
        QuestionDropDown              matlab.ui.control.DropDown
        QuestionDropDownLabel         matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        NUMERICALANALYSISSOLVERLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
  method = app.MethodDropDown.Value;
question = app.QuestionDropDown.Value;
question = strtrim(char(question));

x0 = app.X0EditField.Value;
x1 = app.X1EditField.Value;
tol = app.ToleranceEditField.Value;
h = app.StepSizeEditField.Value;

result = {};

if strcmp(question, 'Newton Q1')

    f = @(x) 1 - 3*x + 0.5*x*exp(x);
    df = @(x) -3 + 0.5*exp(x)*(x + 1);

    x = x0;
    converged = false;

    for k = 1:100
        if abs(df(x)) < 1e-12
            break;
        end

        xnew = x - f(x)/df(x);

        if abs(xnew - x) < tol
            x = xnew;
            converged = true;
            break;
        end

        x = xnew;
    end

    result = {
        'NEWTON-RAPHSON METHOD'
        ['Root = ' num2str(x, '%.8f')]
        ['Iterations = ' num2str(k)]
        ['Converged = ' char(string(converged))]
    };

elseif strcmp(question, 'Secant Q2')

    f = @(x) x - 2*sin(x);
    converged = false;

    for k = 1:100
        f0 = f(x0);
        f1 = f(x1);

        if abs(f1-f0) < 1e-12
            break;
        end

        x2 = x1 - f1*(x1-x0)/(f1-f0);

        if abs(x2-x1) < tol
            x1 = x2;
            converged = true;
            break;
        end

        x0 = x1;
        x1 = x2;
    end

    result = {
        'SECANT METHOD'
        ['Root = ' num2str(x1, '%.8f')]
        ['Iterations = ' num2str(k)]
        ['Converged = ' char(string(converged))]
    };

elseif strcmp(question, 'RK4 Q3')

    if h <= 0 || abs(0.5/h-round(0.5/h)) > 1e-10
        uialert(app.UIFigure, ...
            'Use a positive step size that reaches x = 0.5.', ...
            'Step Size Error');
        return;
    end

    f = @(x,y) x + y;
    x = 0;
    y = 1;
    n = round(0.5/h);

   xValues = zeros(1, n+1);
   yValues = zeros(1, n+1);
    
    xValues(1) = x;
    yValues(1) = y;

    xValues(k+1) = x;
    yValues(k+1) = y;

    result = {
        'RUNGE-KUTTA FOURTH-ORDER METHOD'
        '       x          y'
        sprintf('%8.2f    %.8f', x, y)
    };

    for k = 1:n
        k1 = f(x,y);
        k2 = f(x+h/2,y+h*k1/2);
        k3 = f(x+h/2,y+h*k2/2);
        k4 = f(x+h,y+h*k3);

        y = y + h*(k1+2*k2+2*k3+k4)/6;
        x = x+h;

        result{end+1} = sprintf('%8.2f    %.8f',x,y);
    end

    result{end+1} = ['Final y(0.5) = ' num2str(y, '%.8f')];

else
    uialert(app.UIFigure, ...
        'Please select one of the three questions.', ...
        'Question Error');
    return;
end

app.ResultsTextArea.Value = result;      
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
         app.ResultsTextArea.Value = {'Results will appear here.'};

    app.X0EditField.Value = 1;
    app.X1EditField.Value = 2;
    app.ToleranceEditField.Value = 0.0001;
    app.StepSizeEditField.Value = 0.2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NUMERICALANALYSISSOLVERLabel = uilabel(app.UIFigure);
            app.NUMERICALANALYSISSOLVERLabel.Text = 'NUMERICAL ANALYSIS SOLVER';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'MethodDropDown';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'''Newton-Raphson'',', '''Secant'',', '''Runge-Kutta (RK4)''', 'Option 4'};
            app.MethodDropDown.Value = '''Newton-Raphson'',';
            app.QuestionDropDownLabel = uilabel(app.UIFigure);
            app.QuestionDropDownLabel.Text = 'QuestionDropDown';
            app.QuestionDropDown = uidropdown(app.UIFigure);
            app.QuestionDropDown.Items = {'Newton Q1', 'Secant Q2', 'RK4 Q3'};
            app.QuestionDropDown.Value = 'Newton Q1';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0EditField';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditField.Value = 1;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1EditField';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 2;
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'ToleranceEditField';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditField.Value = 0.0001;
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'StepSizeEditField';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.Value = 0.2;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'SolveButton';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'ClearButton';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'ResultsTextArea';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appbuilding1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end